classdef jpegCompressor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        QFLabel          matlab.ui.control.Label
        LoadImageButton  matlab.ui.control.Button
        CompressButton   matlab.ui.control.Button
        QFSlider         matlab.ui.control.Slider
        QFSliderLabel    matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFile % The image file to be compressed
        Compressed = 0; % Represents whether an image has been compressed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            oldImageFile = app.ImageFile;
            app.ImageFile=uigetfile({'*.png';'*.jpg';'*.tiff'}, 'Select file');

            % If no Image file selected, default to old image file
            if app.ImageFile == 0
                % If no Image File has EVER been selected, tell user
                if isempty(oldImageFile)
                    disp("No input file selected. Please Choose input file!");
                    app.ImageFile = oldImageFile;
                    return;
                end
                app.ImageFile = oldImageFile;
            end
            %Feel free to change the file names, code, etc
            imshow(app.ImageFile, 'Parent', app.UIAxes);
            app.Compressed = 0;

        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)

            if isempty(app.ImageFile)
                disp("No image to compress. Please choose image file.");
                return;
            end
            
            disp(app.ImageFile);
            compressedImg = compress(app.ImageFile, app.QFSlider.Value);
            imshow(compressedImg, 'Parent', app.UIAxes2);
            app.Compressed = 1;
         
        end

        % Value changed function: QFSlider
        function QFSliderValueChanged(app, event)

            % Change QFSlider value to  rounded 
            % number and flush changes
            value = app.QFSlider.Value;
            value = round(value);
            app.QFSlider.Value = value;
            app.QFLabel.Text = int2str(value);
            drawnow();
            
            if isempty(app.ImageFile)
                return;
            end

            if app.Compressed == 0
                return;

            end

            % if app.Compressed == 1
            %     disp(app.ImageFile);
            %     compressedImg = compress(app.ImageFile, app.QFSlider.Value);
            %     imshow(compressedImg, 'Parent', app.UIAxes2);
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Compressed Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.QFSliderLabel = uilabel(app.UIFigure);
            app.QFSliderLabel.Text = 'QF';
            app.QFSlider = uislider(app.UIFigure);
            app.QFSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.QFSlider.ValueChangedFcn = createCallbackFcn(app, @QFSliderValueChanged, true);
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.QFLabel = uilabel(app.UIFigure);
            app.QFLabel.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jpegCompressor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end